classdef TsetlinGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectedFileLabel               matlab.ui.control.Button
        TrainingLamp                    matlab.ui.control.Lamp
        TrainingLampLabel               matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        ControlPanelLabel               matlab.ui.control.Label
        Epochs_1                        matlab.ui.control.EditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        NumberofClasses_1               matlab.ui.control.EditField
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofFeatures_1              matlab.ui.control.EditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        NumberofStates_1                matlab.ui.control.EditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofClauses_1               matlab.ui.control.EditField
        NumberofClausesEditFieldLabel   matlab.ui.control.Label
        Sensitivity_1                   matlab.ui.control.EditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        Threshold_1                     matlab.ui.control.EditField
        ThresholdTEditFieldLabel        matlab.ui.control.Label
        Epochs_2                        matlab.ui.control.EditField
        NumberofClasses_2               matlab.ui.control.EditField
        NumberofFeatures_2              matlab.ui.control.EditField
        NumberofStates_2                matlab.ui.control.EditField
        NumberofClauses_2               matlab.ui.control.EditField
        Sensitivity_2                   matlab.ui.control.EditField
        Threshold_2                     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

        properties (Access = private)
            SelectedFile = '';   % เก็บ path ของไฟล์ที่เลือก
            IsTraining = false;  % ควบคุมสถานะการเทรน
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrainButton.Enable = 'off';
        end

        % Button pushed function: SelectedFileLabel
        function SelectedFileLabelPushed(app, event)
        [file, path] = uigetfile({ ...
            '*.csv', 'CSV Files (*.csv)'; ...
            '*.xlsx', 'Excel Files (*.xlsx)'}, ...
            'Select a file');
    
        if isequal(file, 0)
            uialert(app.UIFigure, 'คุณยังไม่ได้เลือกไฟล์', 'ข้อผิดพลาด', 'Icon', 'warning');
        else
            % ✅ เก็บ path จริงไว้เพื่อให้ Train ใช้ได้
            app.SelectedFile = fullfile(path, file);
    
            % ✅ แสดงชื่อไฟล์ใน label
            app.SelectedFileLabel.Text = file;
    
            % ✅ เปิดปุ่ม Train หลังเลือกไฟล์
            app.TrainButton.Enable = 'on';
        end
    end

        % ✅ เพิ่มฟังก์ชันใหม่ด้านล่างนี้เลย
        function saveParamsToFile(app, ~)
        % สร้างตารางค่าพารามิเตอร์
        params = {
            'Threshold', app.Threshold_1.Value, app.Threshold_2.Value;
            'Sensitivity', app.Sensitivity_1.Value, app.Sensitivity_2.Value;
            'Number of Clauses', app.NumberofClauses_1.Value, app.NumberofClauses_2.Value;
            'Number of States', app.NumberofStates_1.Value, app.NumberofStates_2.Value;
            'Number of Features', app.NumberofFeatures_1.Value, app.NumberofFeatures_2.Value;
            'Number of Classes', app.NumberofClasses_1.Value, app.NumberofClasses_2.Value;
            'Epochs', app.Epochs_1.Value, app.Epochs_2.Value;
        };
    
        % แปลงเป็นตาราง
        T = cell2table(params, ...
            'VariableNames', {'Parameter', 'Value_1', 'Value_2'});
    
        % เลือก path ไฟล์
        [file, path] = uiputfile('parameters.xlsx', 'Save Parameters As');
        if isequal(file, 0)
            return;
        end
        fullPath = fullfile(path, file);
    
        % ✅ เขียนลงไฟล์ .xlsx
        writetable(T, fullPath);
    
        % Popup สำเร็จ
        uialert(app.UIFigure, 'Parameter saved to Excel successfully!', ...
            'Done', 'Icon', 'success');
        end
        function saveGraphToImage(~, axesHandle, app)
                disp("Save graph clicked");  % DEBUG
        
                [file, path] = uiputfile({'*.png'; '*.jpg'}, 'Save Graph As', 'graph.png');
                if isequal(file, 0)
                    return;
                end
        
                fullPath = fullfile(path, file);
        
                try
                    exportgraphics(axesHandle, fullPath);
                    uialert(app.UIFigure, 'Graph saved successfully!', 'Done', 'Icon', 'success');
                catch ME
                    uialert(app.UIFigure, "Error saving graph: " + ME.message, 'Error', 'Icon', 'error');
                end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        app.Threshold_1.Value = '';
        app.Threshold_2.Value = '';
        app.Sensitivity_1.Value = '';
        app.Sensitivity_2.Value = '';
        app.NumberofClauses_1.Value = '';
        app.NumberofClauses_2.Value = '';
        app.NumberofStates_1.Value = '';
        app.NumberofStates_2.Value = '';
        app.NumberofFeatures_1.Value = '';
        app.NumberofFeatures_2.Value = '';
        app.NumberofClasses_1.Value = '';
        app.NumberofClasses_2.Value = '';
        app.Epochs_1.Value = '';
        app.Epochs_2.Value = '';
        app.SelectedFileLabel.Text = 'Select File';
    
        % รีเซ็ตกราฟ
        cla(app.UIAxes);
        title(app.UIAxes, '');
        xlabel(app.UIAxes, 'Epochs');
        ylabel(app.UIAxes, 'Accuracy');
    
        % ดับไฟ
        app.TrainingLamp.Color = [0.5 0.5 0.5];
    
        % หยุดการเทรน
        app.IsTraining = false;
    
        % ล้างการเลือกไฟล
        app.SelectedFile = '';
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % ตรวจสอบว่าเลือกไฟล์แล้วหรือยัง
            if ~isprop(app, 'SelectedFile') || isempty(app.SelectedFile)
                uialert(app.UIFigure, 'กรุณาเลือกไฟล์ก่อนทำการเทรน', 'ไม่มีไฟล์', 'Icon', 'warning');
                return;
            end

            % ทดสอบว่าค่า path อยู่จริง
            disp("SelectedFile = " + app.SelectedFile);
                
            % อ่านไฟล์ CSV หรือ XLSX
            [~, ~, ext] = fileparts(app.SelectedFile);
            try
                switch lower(ext)
                    case '.csv'
                        data = readtable(app.SelectedFile);
                    case '.xlsx'
                        data = readtable(app.SelectedFile);
                    otherwise
                        uialert(app.UIFigure, 'รองรับเฉพาะไฟล์ .csv หรือ .xlsx เท่านั้น', 'ไฟล์ไม่รองรับ');
                        return;
                end
            catch ME
                uialert(app.UIFigure, "เกิดข้อผิดพลาดในการอ่านไฟล์: " + ME.message, 'Error', 'Icon', 'error');
                return;
            end
        
            % ดึงค่าพารามิเตอร์จากช่องกรอก
            T = str2double(app.Threshold_1.Value);
            S = str2double(app.Sensitivity_1.Value);
            Clauses = str2double(app.NumberofClauses_1.Value);
            States = str2double(app.NumberofStates_1.Value);
            Features = str2double(app.NumberofFeatures_1.Value);
            Classes = str2double(app.NumberofClasses_1.Value);
            Epochs = str2double(app.Epochs_1.Value);
        
            % ตรวจค่าที่จำเป็น
            if any(isnan([T, S, Clauses, States, Features, Classes, Epochs]))
                uialert(app.UIFigure, 'กรุณากรอกพารามิเตอร์ให้ครบ', 'ข้อมูลไม่ครบ', 'Icon', 'warning');
                return;
            end
        
            % เปิดไฟ Training
            app.TrainingLamp.Color = [0 1 0];
            app.IsTraining = true;
        
            % ✅ จำลองการฝึกด้วยข้อมูลจากไฟล์ (คุณสามารถแทนที่ด้วย model จริงได้)
            acc_train = zeros(1, Epochs);
            acc_val = zeros(1, Epochs);
            for i = 1:Epochs
                acc_train(i) = 0.65 + rand() * 0.05 + i / (2 * Epochs);
                acc_val(i) = acc_train(i) - rand() * 0.03;
            end
        
            % วาดกราฟ
            cla(app.UIAxes);
            plot(app.UIAxes, 1:Epochs, acc_train, 'b-', 'LineWidth', 2); hold(app.UIAxes, 'on');
            plot(app.UIAxes, 1:Epochs, acc_val, 'Color', [1 0.5 0], 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Epoch');
            ylabel(app.UIAxes, 'Accuracy');
            legend(app.UIAxes, {'Train', 'Validation'}, 'Location', 'southeast');
            title(app.UIAxes, 'Training vs Validation Accuracy');
            
            % สร้างหน้าต่าง popup
            popupFig = uifigure('Name', 'Best Parameter', 'Position', [300 200 600 500]);
            
            % วาดกราฟให้อยู่ตรงกลางด้านบน
            popupAxes = uiaxes(popupFig, 'Position', [100 220 400 250]); % x, y, w, h
            plot(popupAxes, 1:Epochs, acc_train, 'b-', 'LineWidth', 2); hold(popupAxes, 'on');
            plot(popupAxes, 1:Epochs, acc_val, 'Color', [1 0.5 0], 'LineWidth', 2);
            hold(popupAxes, 'off');
            xlabel(popupAxes, 'Epoch');
            ylabel(popupAxes, 'Accuracy');
            title(popupAxes, 'Accuracy (Train vs Validation)');
            legend(popupAxes, {'Train', 'Validation'}, 'Location', 'southeast');
            
            % สร้างข้อความ parameter รวมเป็นข้อความเดียว
            paramText = sprintf([ ...
                'Threshold: %s , %s\n' ...
                'Sensitivity: %s , %s\n' ...
                'Number of Clauses: %s , %s\n' ...
                'Number of States: %s , %s\n' ...
                'Number of Features: %s , %s\n' ...
                'Number of Classes: %s , %s\n' ...
                'Epochs: %s , %s\n'], ...
                app.Threshold_1.Value, app.Threshold_2.Value, ...
                app.Sensitivity_1.Value, app.Sensitivity_2.Value, ...
                app.NumberofClauses_1.Value, app.NumberofClauses_2.Value, ...
                app.NumberofStates_1.Value, app.NumberofStates_2.Value, ...
                app.NumberofFeatures_1.Value, app.NumberofFeatures_2.Value, ...
                app.NumberofClasses_1.Value, app.NumberofClasses_2.Value, ...
                app.Epochs_1.Value, app.Epochs_2.Value);
            
            % แสดง label ค่าพารามิเตอร์อยู่ตรงกลางด้านล่าง
            paramLabel = uilabel(popupFig, ...
                'Text', paramText, ...
                'Position', [50 70 500 120], ...
                'FontSize', 12, ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'top');
            
            % ปุ่ม Save ด้านล่าง
            saveButton = uibutton(popupFig, ...
                'Text', 'Save Parameters', ...
                'Position', [250 20 100 30], ...
                'ButtonPushedFcn', @(btn,event) saveParamsToFile(paramText));
            saveGraphBtn = uibutton(popupFig, ...
                'Text', 'Save Graph', ...
                'Position', [420 20 100 30], ...
                'ButtonPushedFcn', @(btn,event) saveGraphToImage(app, popupAxes));
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        % ดับไฟ (เปลี่ยนสี Lamp เป็นเทา)
        app.TrainingLamp.Color = [0.5 0.5 0.5]; % สีเทา
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
        mode = app.Switch2.Value;
    
        % List ชื่อ component ด้านขวา
        rightInputs = [
            app.Threshold_2, app.Sensitivity_2, app.NumberofClauses_2, ...
            app.NumberofStates_2, app.NumberofFeatures_2, ...
            app.NumberofClasses_2, app.Epochs_2
        ];
    
        if strcmp(mode, 'Manual Mode')
            % ซ่อนช่องด้านขวา
            for i = 1:numel(rightInputs)
                rightInputs(i).Visible = 'off';
            end
        else
            % แสดงช่องด้านขวา
            for i = 1:numel(rightInputs)
                rightInputs(i).Visible = 'on';
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.Threshold_2 = uieditfield(app.UIFigure, 'text');
            app.Sensitivity_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofClauses_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofStates_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeatures_2 = uieditfield(app.UIFigure, 'text');
            app.NumberofClasses_2 = uieditfield(app.UIFigure, 'text');
            app.Epochs_2 = uieditfield(app.UIFigure, 'text');
            app.ThresholdTEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdTEditFieldLabel.Text = 'Threshold (T):';
            app.Threshold_1 = uieditfield(app.UIFigure, 'text');
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity :';
            app.Sensitivity_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClausesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClausesEditFieldLabel.Text = 'Number of Clauses :';
            app.NumberofClauses_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States :';
            app.NumberofStates_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features :';
            app.NumberofFeatures_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClassesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes :';
            app.NumberofClasses_1 = uieditfield(app.UIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.UIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs :';
            app.Epochs_1 = uieditfield(app.UIFigure, 'text');
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Sweep Mode', 'Manual Mode'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Sweep Mode';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainingLampLabel = uilabel(app.UIFigure);
            app.TrainingLampLabel.Text = 'Training';
            app.TrainingLamp = uilamp(app.UIFigure);
            app.SelectedFileLabel = uibutton(app.UIFigure, 'push');
            app.SelectedFileLabel.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileLabelPushed, true);
            app.SelectedFileLabel.Text = 'Select File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsetlinGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end